classdef UIStart_10262023_V2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MLAnalysisAppUIFigure           matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        Image                           matlab.ui.control.Image
        RunninganalysisLabel            matlab.ui.control.Label
        CancelButton                    matlab.ui.control.Button
        Panel_2                         matlab.ui.container.Panel
        DebugCodeLeaveUncheckedCheckBox  matlab.ui.control.CheckBox
        RootFilenameEditField           matlab.ui.control.EditField
        RootFilenameLabel               matlab.ui.control.Label
        MATLABExportDirectoryEditField  matlab.ui.control.EditField
        MATLABExportDirectoryLabel      matlab.ui.control.Label
        IMARISExcelDirectoryEditField   matlab.ui.control.EditField
        IMARISExcelDirectoryLabel       matlab.ui.control.Label
        BrowseButton_2                  matlab.ui.control.Button
        BrowseButton                    matlab.ui.control.Button
        RunAnalysisButton               matlab.ui.control.StateButton
        Label                           matlab.ui.control.Label
        ImportModeButtonGroup           matlab.ui.container.ButtonGroup
        LiveThunderTrackButton          matlab.ui.control.RadioButton
        LiveThunderNonTrackPooledButton  matlab.ui.control.RadioButton
        LiveThunderNonTrackUnpooledButton  matlab.ui.control.RadioButton
        FixedAiryscanButton             matlab.ui.control.RadioButton
        OrganelleMachineLearningAnalysisLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton_2
        function BrowseButton_2Pushed(app, event)
            f = figure('Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
            dir = uigetdir(cd);
            app.MATLABExportDirectoryEditField.Value = dir;
            delete(f); %delete the dummy figure
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            f = figure('Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
            dir = uigetdir(cd);
            app.IMARISExcelDirectoryEditField.Value = dir;
            delete(f); %delete the dummy figure
        end

        % Value changed function: RunAnalysisButton
        function RunAnalysisButtonValueChanged(app, event)
            global canceled;
            canceled = 0;


            %Detemrine import mode
            selectedmode = app.ImportModeButtonGroup.SelectedObject;
            if contains(selectedmode.Text, 'Fixed')
                mode = 'Fixed';
            elseif contains(selectedmode.Text, 'Live NonTrack')
                if contains(selectedmode.Text, 'Unpooled')
                    mode = 'Live-nonpool';
                else
                    mode = 'Live-pool';
                end
            elseif contains(selectedmode.Text, 'Live Track')
                mode = 'Track';
            end
            
            
            %Determine directories and filenames
            exp_dir = app.MATLABExportDirectoryEditField.Value;
            imp_dir = app.IMARISExcelDirectoryEditField.Value;
            root_name = app.RootFilenameEditField.Value;

            if(app.DebugCodeLeaveUncheckedCheckBox.Value == 1)
                mode = 'DEBUG';
                app.MATLABExportDirectoryEditField.Value = 'the coded directories.';
                app.RootFilenameEditField.Value = 'Debug';
            end

            %Pass values to the main code as a saved .mat
            save('UIInputValues.mat')
            
            %shift to loading panel
            app.Panel_2.Visible = "off";
            app.Panel.Visible = "on";

            try %will change to "catch" if an error occurs
                global pass    %pass is needed due to odd behavior running outside code where the original app becomes inaccessible
                pass = app;
                MainScript_10262023_V2 %Run the main script

                global pass    %reaches this part of the code only if analysis is completed witohut error
                pass.Image.ImageSource = fullfile(fileparts(mfilename('fullpath')), 'Done 2.png');
                pass.RunninganalysisLabel.Text = 'Analysis Complete!';
                uialert(pass.MLAnalysisAppUIFigure, {append(pass.RootFilenameEditField.Value, ' machine learning analysis is complete!'), ...
                    append('Exported data can be found at ', pass.MATLABExportDirectoryEditField.Value)},'Analysis Complete!', 'Icon', 'success') 

            catch MExc %MExc stores information on the error
                global pass
                
                message = MExc.message;
                stack = MExc.stack;

                if length(stack) == 2 %stack will always be greater than 2 unless its this specific error type
                    details = "Terminated by user.";
                else
                    for i = 1:(length(stack) - 2)
                        if i == 1
                           details = "Exception thrown at ";
                        end

                        details = append(details, stack(i).name, " (Line ", string(stack(i).line), ")");

                        if i ~= (length(stack) - 2)
                            details = append(details, " -> ");
                        end

                    end

                end
                
                
                pass.Image.ImageSource = fullfile(fileparts(mfilename('fullpath')), 'Error 2.png');
                pass.RunninganalysisLabel.Text = 'Error!';
                uialert(pass.MLAnalysisAppUIFigure, {'An error has occurred during the analysis.', ...
                    message, '', char(details)},'Error!')
                beep %plays the default error sound

            end
            
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            global canceled;
            global pass;
            
            if isequal(pass.RunninganalysisLabel.Text, 'Error!') || ...
                    isequal(pass.RunninganalysisLabel.Text, 'Analysis Complete!') %will not do anything except play sound if not running code
                beep
                return
            end
            d = uiconfirm(app.MLAnalysisAppUIFigure,'Are you sure you want to stop the analysis?', 'Warning', 'Options', {'Yes', 'No'}); %confirmation window
            switch d
                case 'Yes'
                    canceled = 1; %will cancel code the next time 'cancelcheck' is ran
                    app.RunninganalysisLabel.Text = 'Cancelling...';
                    app.Image.ImageSource = fullfile(fileparts(mfilename('fullpath')), 'Cancel 2.gif');
                case 'No'
                    return
            end
         
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MLAnalysisAppUIFigure = uifigure('Visible', 'off');
            app.MLAnalysisAppUIFigure.Name = 'ML Analysis App';
            app.Panel_2 = uipanel(app.MLAnalysisAppUIFigure);
            app.OrganelleMachineLearningAnalysisLabel = uilabel(app.Panel_2);
            app.OrganelleMachineLearningAnalysisLabel.Text = 'Organelle Machine Learning Analysis';
            app.ImportModeButtonGroup = uibuttongroup(app.Panel_2);
            app.ImportModeButtonGroup.Title = 'Import Mode';
            app.FixedAiryscanButton = uiradiobutton(app.ImportModeButtonGroup);
            app.FixedAiryscanButton.Text = 'Fixed / Airyscan';
            app.FixedAiryscanButton.Value = true;
            app.LiveThunderNonTrackUnpooledButton = uiradiobutton(app.ImportModeButtonGroup);
            app.LiveThunderNonTrackUnpooledButton.Text = 'Live / Thunder NonTrack - Unpooled';
            app.LiveThunderNonTrackPooledButton = uiradiobutton(app.ImportModeButtonGroup);
            app.LiveThunderNonTrackPooledButton.Text = 'Live / Thunder NonTrack - Pooled';
            app.LiveThunderTrackButton = uiradiobutton(app.ImportModeButtonGroup);
            app.LiveThunderTrackButton.Text = 'Live / Thunder Track';
            app.Label = uilabel(app.Panel_2);
            app.Label.Text = '';
            app.RunAnalysisButton = uibutton(app.Panel_2, 'state');
            app.RunAnalysisButton.ValueChangedFcn = createCallbackFcn(app, @RunAnalysisButtonValueChanged, true);
            app.RunAnalysisButton.Text = 'Run Analysis';
            app.BrowseButton = uibutton(app.Panel_2, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse...';
            app.BrowseButton_2 = uibutton(app.Panel_2, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_2Pushed, true);
            app.BrowseButton_2.Text = 'Browse...';
            app.IMARISExcelDirectoryLabel = uilabel(app.Panel_2);
            app.IMARISExcelDirectoryLabel.Text = 'IMARIS Excel Directory:';
            app.IMARISExcelDirectoryEditField = uieditfield(app.Panel_2, 'text');
            app.MATLABExportDirectoryLabel = uilabel(app.Panel_2);
            app.MATLABExportDirectoryLabel.Text = 'MATLAB Export Directory:';
            app.MATLABExportDirectoryEditField = uieditfield(app.Panel_2, 'text');
            app.RootFilenameLabel = uilabel(app.Panel_2);
            app.RootFilenameLabel.Text = 'Root Filename:';
            app.RootFilenameEditField = uieditfield(app.Panel_2, 'text');
            app.DebugCodeLeaveUncheckedCheckBox = uicheckbox(app.Panel_2);
            app.DebugCodeLeaveUncheckedCheckBox.Text = 'Debug Code (Leave Unchecked)';
            app.Panel = uipanel(app.MLAnalysisAppUIFigure);
            app.Panel.Visible = 'off';
            app.CancelButton = uibutton(app.Panel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.RunninganalysisLabel = uilabel(app.Panel);
            app.RunninganalysisLabel.Text = 'Running analysis...';
            app.Image = uiimage(app.Panel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Working (2).gif');
            % Show the figure after all components are created
            app.MLAnalysisAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UIStart_10262023_V2

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.MLAnalysisAppUIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.MLAnalysisAppUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MLAnalysisAppUIFigure)
        end
    end
end